classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout1      matlab.ui.container.GridLayout
        Panel2           matlab.ui.container.Panel
        GridLayout3      matlab.ui.container.GridLayout
        Label_2          matlab.ui.control.Label
        Label            matlab.ui.control.Label
        ConfidenceLabel  matlab.ui.control.Label
        TIMELabel        matlab.ui.control.Label
        Panel1           matlab.ui.container.Panel
        Button           matlab.ui.control.Button
        DropDown         matlab.ui.control.DropDown
        DropDownLabel    matlab.ui.control.Label
        UIAxes4          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes1          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        current_image; %
        current_time;
    end
   
    methods(Static) 
        function  include_path()
            addpath("../lib/");
            addpath("../images");
            addpath("../images/tempelates");
        end
    end
    
    methods (Access = private)
        
        
        function set_ui_axes(app)
            
            axs = [app.UIAxes1 app.UIAxes2 app.UIAxes3 app.UIAxes4];
            titles = ["Original Image" "Masked Image" "Segmented Image" "Final"];
            
            for i=1:4
                axs(i).XTick = [];
                axs(i).YTick = [];
                xlabel(axs(i), '');
                ylabel(axs(i), '');
                title(axs(i), titles(i));

                
            end
            
        end       

        function set_dropDown(app)
            options = strings(1,10);
            for i=1:10
                options(i) = "im" + num2str(i+1);
            end
            app.DropDown.Items = options;
        end


        function update_gui(app,path)
            
            path_c = path + ".png";
            app.current_image = imread(path_c);

            imshow(app.current_image,'Parent',app.UIAxes1);
            
            img = filter_bright(app.current_image);
            imshow(img,'Parent',app.UIAxes2);
            
            img = compartment(img);
            imshow(img,'Parent',app.UIAxes3);
            
            img = isolate_digits(img);
            img = deskew_image(img);
            final = snip(img);
            imshow(final,'Parent',app.UIAxes4);
            [T,C] = guess_time(final);
            
            app.current_time = mat2str(T);
            app.Label.Text = app.current_time;
            app.Label_2.Text = num2str(C);
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.include_path();
            app.set_dropDown();
            app.set_ui_axes();
            path = app.DropDown.Value;
            app.update_gui(path);
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)
            value = app.DropDown.Value;
            app.update_gui(value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout1 = uigridlayout(app.UIFigure);
            app.UIAxes1 = uiaxes(app.GridLayout1);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout1);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout1);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout1);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Panel1 = uipanel(app.GridLayout1);
            app.Panel1.Title = 'Panel';
            app.DropDownLabel = uilabel(app.Panel1);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel1);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.Button = uibutton(app.Panel1, 'push');
            app.Panel2 = uipanel(app.GridLayout1);
            app.Panel2.Title = 'Panel2';
            app.GridLayout3 = uigridlayout(app.Panel2);
            app.TIMELabel = uilabel(app.GridLayout3);
            app.TIMELabel.Text = 'TIME';
            app.ConfidenceLabel = uilabel(app.GridLayout3);
            app.ConfidenceLabel.Text = 'Confidence';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end